classdef LLC_Calculator < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        AppWindow               matlab.ui.Figure
        TabGroup                matlab.ui.container.TabGroup
        Input                   matlab.ui.container.Tab
        DSsLabel                matlab.ui.control.Label
        Vin_minVEditFieldLabel  matlab.ui.control.Label
        VinminEditField         matlab.ui.control.NumericEditField
        Vin_maxVEditFieldLabel  matlab.ui.control.Label
        VinmaxEditField         matlab.ui.control.NumericEditField
        Vout_1VLabel            matlab.ui.control.Label
        Vo1EditField            matlab.ui.control.NumericEditField
        Vout_2VLabel            matlab.ui.control.Label
        Vo2EditField            matlab.ui.control.NumericEditField
        Vout_3VLabel            matlab.ui.control.Label
        Vo3EditField            matlab.ui.control.NumericEditField
        Vout_4VLabel            matlab.ui.control.Label
        Vo4EditField            matlab.ui.control.NumericEditField
        Pout_1WLabel            matlab.ui.control.Label
        Po1EditField            matlab.ui.control.NumericEditField
        Pout_2WLabel            matlab.ui.control.Label
        Po2EditField            matlab.ui.control.NumericEditField
        Pout_3WLabel            matlab.ui.control.Label
        Po3EditField            matlab.ui.control.NumericEditField
        Pout_4WLabel            matlab.ui.control.Label
        Po4EditField            matlab.ui.control.NumericEditField
        EffLabel                matlab.ui.control.Label
        EffEditField            matlab.ui.control.NumericEditField
        DSAxes                  matlab.ui.control.UIAxes
        btnPlotDS               matlab.ui.control.Button
        NoteLabel               matlab.ui.control.Label
        NoteLabel_2             matlab.ui.control.Label
        NoteLabel_3             matlab.ui.control.Label
        NoteLabel_4             matlab.ui.control.Label
        ResTankTab              matlab.ui.container.Tab
        IPsLabel                matlab.ui.control.Label
        maxfskHzLabel           matlab.ui.control.Label
        FsmaxEditField          matlab.ui.control.NumericEditField
        minfskHzLabel           matlab.ui.control.Label
        FsminEditField          matlab.ui.control.NumericEditField
        Vin_norVLabel           matlab.ui.control.Label
        VinnorEditField         matlab.ui.control.NumericEditField
        Vo_norVLabel            matlab.ui.control.Label
        VonorEditField          matlab.ui.control.NumericEditField
        Ln_preLabel             matlab.ui.control.Label
        LnPreEditField          matlab.ui.control.NumericEditField
        Qe_preLabel             matlab.ui.control.Label
        LnPreEditField_2        matlab.ui.control.NumericEditField
        GainCurveTab            matlab.ui.container.Tab
        LnEditFieldLabel        matlab.ui.control.Label
        numLn                   matlab.ui.control.NumericEditField
        QeEditFieldLabel        matlab.ui.control.Label
        numQe                   matlab.ui.control.NumericEditField
        btnPlotGC               matlab.ui.control.Button
        GainCurAxes             matlab.ui.control.UIAxes
    end


    properties (Access = private)
        fn % Description
        Qe_arr
    end

    methods (Access = private)
    
        function updataPlotGC(app)
            
            % Get app user input
            Ln_a = app.numLn.Value;
            Qe_a = app.numQe.Value;
            app.Qe_arr = Qe_a.*[0.1 0.5 1 2 4];
            app.fn = linspace(0,2.5,1000);
            for k = 1:5
                Mg = 1 ./ sqrt((1+1./Ln_a-1./(Ln_a.*app.fn.^2)).^2 ...      
                         + ((app.fn-1./app.fn).*app.Qe_arr(k)).^2); % Plot Gain Curve  
                % Create gain curve plot in proper axes
                if app.Qe_arr(k)==1
                    plot(app.GainCurAxes,app.fn,Mg,'r--','LineWidth',2);
                else
                    plot(app.GainCurAxes,app.fn,Mg);
                end
                hold(app.GainCurAxes,'on');
                grid(app.GainCurAxes,'minor');
                legend(app.GainCurAxes,'Qe = 0.1','Qe = 0.5',...
                    'Qe = 1','Qe = 2','Qe = 4');              
            end
            ylim(app.GainCurAxes,[0,1.5]);
            
        end
        
        function updataPlotDS(app)
            
            % Get app user input
            Vin_min = app.VinminEditField.Value;
            Vin_max = app.VinmaxEditField.Value;
            Vout1 = app.Vo1EditField.Value;
            Vout2 = app.Vo2EditField.Value;
            Vout3 = app.Vo3EditField.Value;
            Vout4 = app.Vo4EditField.Value;
            Po1 = app.Po1EditField.Value;
            Po2 = app.Po2EditField.Value;
            Po3 = app.Po3EditField.Value;
            Po4 = app.Po4EditField.Value;
            Io1 = Po1/Vout1;
            Io2 = Po2/Vout2;
            Io3 = Po3/Vout3;
            Io4 = Po4/Vout4;
            
            % Plot the working zoon

            hold(app.DSAxes,'on');
            grid(app.DSAxes,'minor');
            
            Io12_arr = [Io2:0.1:Io1];
            Vout12_arr = -12.5*Io12_arr+868.75;
            plot(app.DSAxes,Io12_arr,Vout12_arr,'b','LineWidth',2);
            plot(app.DSAxes,Io2,Vout2,'r*');
            text(app.DSAxes,0.9*Io2,1.05*Vout2,'(Io2,Vo2)');
            
            Vo13_arr = [Vout3:Vout1];
            Io13_arr = Io3*ones(1,length(Vo13_arr));
            plot(app.DSAxes,Io13_arr,Vo13_arr,'b','LineWidth',2);
            plot(app.DSAxes,Io1,Vout1,'r*');
            text(app.DSAxes,0.8*Io1,Vout1,'(Io1,Vo1)');
            
            Io2_arr = [0:0.1:Io2];
            Vout2_arr = Vout2*ones(1,length(Io2_arr));
            plot(app.DSAxes,Io2_arr,Vout2_arr,'b','LineWidth',2);
            
            Io34_arr = [Io4:0.1:Io3];
            Vout34_arr = 12*Io34_arr-100;
            plot(app.DSAxes,Io34_arr,Vout34_arr,'b','LineWidth',2);
            plot(app.DSAxes,Io3,Vout3,'r*');
            text(app.DSAxes,0.8*Io3,Vout3,'(Io3,Vo3)');
            
            Io4_arr = [0:Io4];
            Vout4_arr = Vout4*ones(1,length(Io4_arr));
            plot(app.DSAxes,Io4_arr,Vout4_arr,'b','LineWidth',2);
            plot(app.DSAxes,Io4,Vout4,'r*');
            text(app.DSAxes,0.9*Io4,0.9*Vout4,'(Io4,Vo4)');
            
            ylim(app.DSAxes,[0,max(max(Vout1,Vout2),max(Vout3,Vout4))+100]);
        end
        
        function ResTanCal(app)
            
            % Get app user input
            
        end
        
    end


    methods (Access = private)

        % Callback function
        function PlotButtonPushed(app, event)
             updataPlotGC(app)
        end

        % Button pushed function: btnPlotDS
        function btnPlotDSButtonPushed(app, event)
            updataPlotDS(app)
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.AppWindow = uifigure;
            app.AppWindow.Name = 'UI Figure';
            setAutoResize(app, app.AppWindow, true)
            app.TabGroup = uitabgroup(app.AppWindow);
            app.Input = uitab(app.TabGroup);
            app.Input.Title = 'Input';
            app.Input.Units = 'pixels';
            app.DSsLabel = uilabel(app.Input);
            app.DSsLabel.Text = 'Design Sepcifications';
            app.Vin_minVEditFieldLabel = uilabel(app.Input);
            app.Vin_minVEditFieldLabel.Text = 'Vin_min(V)';
            app.VinminEditField = uieditfield(app.Input, 'numeric');
            app.VinminEditField.Limits = [600 650];
            app.VinminEditField.Value = 600;
            app.Vin_maxVEditFieldLabel = uilabel(app.Input);
            app.Vin_maxVEditFieldLabel.Text = 'Vin_max(V)';
            app.VinmaxEditField = uieditfield(app.Input, 'numeric');
            app.VinmaxEditField.Limits = [760 800];
            app.VinmaxEditField.Value = 780;
            app.Vout_1VLabel = uilabel(app.Input);
            app.Vout_1VLabel.Text = 'Vout_1(V)';
            app.Vo1EditField = uieditfield(app.Input, 'numeric');
            app.Vo1EditField.Limits = [200 800];
            app.Vo1EditField.Value = 400;
            app.Vout_2VLabel = uilabel(app.Input);
            app.Vout_2VLabel.Text = 'Vout_2(V)';
            app.Vo2EditField = uieditfield(app.Input, 'numeric');
            app.Vo2EditField.Limits = [200 800];
            app.Vo2EditField.Value = 450;
            app.Vout_3VLabel = uilabel(app.Input);
            app.Vout_3VLabel.Text = 'Vout_3(V)';
            app.Vo3EditField = uieditfield(app.Input, 'numeric');
            app.Vo3EditField.Limits = [200 800];
            app.Vo3EditField.Value = 350;
            app.Vout_4VLabel = uilabel(app.Input);
            app.Vout_4VLabel.Text = 'Vout_4(V)';
            app.Vo4EditField = uieditfield(app.Input, 'numeric');
            app.Vo4EditField.Limits = [200 800];
            app.Vo4EditField.Value = 200;
            app.Pout_1WLabel = uilabel(app.Input);
            app.Pout_1WLabel.Text = 'Pout_1(W)';
            app.Po1EditField = uieditfield(app.Input, 'numeric');
            app.Po1EditField.Limits = [7500 30000];
            app.Po1EditField.Value = 15000;
            app.Pout_2WLabel = uilabel(app.Input);
            app.Pout_2WLabel.Text = 'Pout_2(W)';
            app.Po2EditField = uieditfield(app.Input, 'numeric');
            app.Po2EditField.Limits = [7500 30000];
            app.Po2EditField.Value = 15000;
            app.Pout_3WLabel = uilabel(app.Input);
            app.Pout_3WLabel.Text = 'Pout_3(W)';
            app.Po3EditField = uieditfield(app.Input, 'numeric');
            app.Po3EditField.Limits = [7500 30000];
            app.Po3EditField.Value = 13125;
            app.Pout_4WLabel = uilabel(app.Input);
            app.Pout_4WLabel.Text = 'Pout_4(W)';
            app.Po4EditField = uieditfield(app.Input, 'numeric');
            app.Po4EditField.Limits = [3000 30000];
            app.Po4EditField.Value = 5000;
            app.EffLabel = uilabel(app.Input);
            app.EffLabel.Text = 'Eff (%)';
            app.EffEditField = uieditfield(app.Input, 'numeric');
            app.EffEditField.Limits = [96 99];
            app.EffEditField.Value = 98;
            app.DSAxes = uiaxes(app.Input);
            title(app.DSAxes, 'Output Voltage vs. Input Voltage');
            xlabel(app.DSAxes, 'Input Voltage (V)');
            ylabel(app.DSAxes, 'Output Voltage (V)');
            app.btnPlotDS = uibutton(app.Input, 'push');
            app.btnPlotDS.ButtonPushedFcn = createCallbackFcn(app, @btnPlotDSButtonPushed, true);
            app.btnPlotDS.Text = 'Plot';
            app.NoteLabel = uilabel(app.Input);
            app.NoteLabel.Text = 'Note: 1. Vout_1 is defined as maximum output voltage when the output current could reach maximum value.';
            app.NoteLabel_2 = uilabel(app.Input);
            app.NoteLabel_2.Text = '           2. Vout_2 is defined as maximum output voltage with a derated output current. ';
            app.NoteLabel_3 = uilabel(app.Input);
            app.NoteLabel_3.Text = '           3. Vout_3 is defined as minimum output voltage when the output current could reach maximum value.';
            app.NoteLabel_4 = uilabel(app.Input);
            app.NoteLabel_4.Text = '           4. Vout_4 is defined as minimum output voltage with a derated output current.';
            app.ResTankTab = uitab(app.TabGroup);
            app.ResTankTab.Title = 'Res. Tank';
            app.ResTankTab.Units = 'pixels';
            app.IPsLabel = uilabel(app.ResTankTab);
            app.IPsLabel.Text = 'Internal Parameters';
            app.maxfskHzLabel = uilabel(app.ResTankTab);
            app.maxfskHzLabel.Text = 'max. fs (kHz)';
            app.FsmaxEditField = uieditfield(app.ResTankTab, 'numeric');
            app.FsmaxEditField.Limits = [150 280];
            app.FsmaxEditField.Value = 200;
            app.minfskHzLabel = uilabel(app.ResTankTab);
            app.minfskHzLabel.Text = 'min. fs (kHz)';
            app.FsminEditField = uieditfield(app.ResTankTab, 'numeric');
            app.FsminEditField.Limits = [50 120];
            app.FsminEditField.Value = 80;
            app.Vin_norVLabel = uilabel(app.ResTankTab);
            app.Vin_norVLabel.Text = 'Vin_nor(V)';
            app.VinnorEditField = uieditfield(app.ResTankTab, 'numeric');
            app.VinnorEditField.Limits = [600 800];
            app.VinnorEditField.Value = 760;
            app.Vo_norVLabel = uilabel(app.ResTankTab);
            app.Vo_norVLabel.Text = 'Vo_nor(V)';
            app.VonorEditField = uieditfield(app.ResTankTab, 'numeric');
            app.VonorEditField.Limits = [200 800];
            app.VonorEditField.Value = 400;
            app.Ln_preLabel = uilabel(app.ResTankTab);
            app.Ln_preLabel.Text = 'Ln_pre';
            app.LnPreEditField = uieditfield(app.ResTankTab, 'numeric');
            app.LnPreEditField.Limits = [3 8];
            app.LnPreEditField.Value = 5;
            app.Qe_preLabel = uilabel(app.ResTankTab);
            app.Qe_preLabel.Text = 'Qe_pre';
            app.LnPreEditField_2 = uieditfield(app.ResTankTab, 'numeric');
            app.LnPreEditField_2.Limits = [0.2 0.8];
            app.LnPreEditField_2.Value = 0.5;
            app.GainCurveTab = uitab(app.TabGroup);
            app.GainCurveTab.Title = 'Gain Curve';
            app.GainCurveTab.Units = 'pixels';
            app.LnEditFieldLabel = uilabel(app.GainCurveTab);
            app.LnEditFieldLabel.Text = 'Ln';
            app.numLn = uieditfield(app.GainCurveTab, 'numeric');
            app.numLn.Limits = [1 10];
            app.numLn.Value = 5;
            app.QeEditFieldLabel = uilabel(app.GainCurveTab);
            app.QeEditFieldLabel.Text = 'Qe';
            app.numQe = uieditfield(app.GainCurveTab, 'numeric');
            app.numQe.Limits = [0.2 1.5];
            app.numQe.Value = 1;
            app.btnPlotGC = uibutton(app.GainCurveTab, 'push');
            app.btnPlotGC.ButtonPushedFcn = createCallbackFcn(app, @PlotButtonPushed, true);
            app.btnPlotGC.Text = 'Plot';
            app.GainCurAxes = uiaxes(app.GainCurveTab);
            title(app.GainCurAxes, 'Gain Curve');
            xlabel(app.GainCurAxes, 'fn');
            ylabel(app.GainCurAxes, 'Mg');
        end
    end

    methods (Access = public)

        % Construct app
        function app = LLC_Calculator()

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.AppWindow)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.AppWindow)
        end
    end
end